classdef CNMFe_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ChangeallvaluesLabel  matlab.ui.control.Label
        FramerateLabel        matlab.ui.control.Label
        gSigLabel             matlab.ui.control.Label
        CnLabel               matlab.ui.control.Label
        PNRLabel              matlab.ui.control.Label
        sf_allset             matlab.ui.control.NumericEditField
        gSig_allset           matlab.ui.control.NumericEditField
        cn_allset             matlab.ui.control.NumericEditField
        pnr_allset            matlab.ui.control.NumericEditField
        DoneButton            matlab.ui.control.Button
        LoadDataButton        matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    
    properties (Access = public)
        theFiles % Description
    end
    
    methods (Access = public)
        
        function update_tabe(app,ix,val)
           t=app.UITable.Data{:,ix};
           t = cellfun(@(x) {val},t,'UniformOutput',false);
           app.UITable.Data(:,ix)=cell2table(t);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
             app.theFiles = uipickfiles('FilterSpec','*.h5');
            
            File=app.theFiles';
            for i=1:size(File,1)
                PNR{i,1}=7;
                corr{i,1}=0.7;
                sf{i,1}=10;
                gSig{i,1}=2.5;
                Get{i,1}='Press me';
            end     
            t=table(File,PNR,corr,gSig,sf,Get);
            app.UITable.Data = t;        
            s = uistyle('BackgroundColor','green');
            addStyle(app.UITable,s,'cell',[(1:size(File,1))',ones(size(File,1),1)*6]);
                        
            app.pnr_allset.Editable = 'on';
            app.pnr_allset.Enable = 'on';
            
            app.cn_allset.Editable = 'on';
            app.cn_allset.Enable = 'on';
            
            app.gSig_allset.Editable = 'on';
            app.gSig_allset.Enable = 'on';
            
            app.sf_allset.Editable = 'on';
            app.sf_allset.Enable = 'on';
            
            app.UITable.Enable = 'on';
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
            indices = event.Indices;
            if indices(2)==6
                file=app.UITable.Data{indices(1),1};
                file=file{1, 1};
                Gsig=cell2mat(app.UITable.Data{indices(1),4});
                PNR=cell2mat(app.UITable.Data{indices(1),2});
                Cn=cell2mat(app.UITable.Data{indices(1),3});
                [pnr_out,cn_out,Mask,ind]=estimate_PNR_Coor_Thr(Gsig,file,Cn,PNR);
                [filepath,name,~]=fileparts(app.UITable.Data{indices(1),1}{:});
                save([filepath,filesep,name,'.mat'],'Mask','-append');  
                save([filepath,filesep,name,'.mat'],'ind','-append');  
                app.UITable.Data{indices(1),2}={pnr_out};
                app.UITable.Data{indices(1),3}={cn_out};
            end
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            assignin('base','parin',app.UITable.Data)
            delete(app)
        end

        % Value changed function: pnr_allset
        function pnr_allsetValueChanged(app, event)
            val = app.pnr_allset.Value;
            update_tabe(app,2,val)
            
        end

        % Value changed function: cn_allset
        function cn_allsetValueChanged(app, event)
            val = app.cn_allset.Value;
            update_tabe(app,3,val)
            
        end

        % Value changed function: gSig_allset
        function gSig_allsetValueChanged(app, event)
             val = app.gSig_allset.Value;
            update_tabe(app,4,val)
            
        end

        % Value changed function: sf_allset
        function sf_allsetValueChanged(app, event)
            val = app.sf_allset.Value;
            update_tabe(app,5,val)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'File'; 'PNR'; 'Corr'; 'gSig'; 'Frame rate'; 'Get'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Enable = 'off';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done!';
            app.pnr_allset = uieditfield(app.UIFigure, 'numeric');
            app.pnr_allset.ValueChangedFcn = createCallbackFcn(app, @pnr_allsetValueChanged, true);
            app.pnr_allset.Editable = 'off';
            app.pnr_allset.Enable = 'off';
            app.pnr_allset.Value = 7;
            app.cn_allset = uieditfield(app.UIFigure, 'numeric');
            app.cn_allset.ValueChangedFcn = createCallbackFcn(app, @cn_allsetValueChanged, true);
            app.cn_allset.Editable = 'off';
            app.cn_allset.Enable = 'off';
            app.cn_allset.Value = 0.5;
            app.gSig_allset = uieditfield(app.UIFigure, 'numeric');
            app.gSig_allset.ValueChangedFcn = createCallbackFcn(app, @gSig_allsetValueChanged, true);
            app.gSig_allset.Editable = 'off';
            app.gSig_allset.Enable = 'off';
            app.gSig_allset.Value = 2.5;
            app.sf_allset = uieditfield(app.UIFigure, 'numeric');
            app.sf_allset.ValueChangedFcn = createCallbackFcn(app, @sf_allsetValueChanged, true);
            app.sf_allset.Editable = 'off';
            app.sf_allset.Enable = 'off';
            app.sf_allset.Value = 10;
            app.PNRLabel = uilabel(app.UIFigure);
            app.PNRLabel.Text = 'PNR';
            app.CnLabel = uilabel(app.UIFigure);
            app.CnLabel.Text = 'Corr';
            app.gSigLabel = uilabel(app.UIFigure);
            app.gSigLabel.Text = 'gSig';
            app.FramerateLabel = uilabel(app.UIFigure);
            app.FramerateLabel.Text = 'Frame rate';
            app.ChangeallvaluesLabel = uilabel(app.UIFigure);
            app.ChangeallvaluesLabel.Text = 'Change all values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CNMFe_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end